classdef VisioGuard_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ventana1               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        VentanaInicio_Tab      matlab.ui.container.Tab
        GlaucoScanLabel        matlab.ui.control.Label
        Fecha_Label            matlab.ui.control.Label
        Usuario_Image          matlab.ui.control.Image
        Nombre_Label           matlab.ui.control.Label
        Apellidos_Label        matlab.ui.control.Label
        ErrorInicio_Label      matlab.ui.control.Label
        Nombre_EditField       matlab.ui.control.EditField
        Apellidos_EditField    matlab.ui.control.EditField
        Continuar_Button       matlab.ui.control.Button
        VisioGuard_Image       matlab.ui.control.Image
        MU_Image               matlab.ui.control.Image
        Marco_Image            matlab.ui.control.Image
        Tab2                   matlab.ui.container.Tab
        ErrorInsertar_Label    matlab.ui.control.Label
        NuevoPaciente_Button   matlab.ui.control.Button
        Nombreyapellido_Label  matlab.ui.control.Label
        EyeFundus_Image        matlab.ui.control.Image
        Inserteimagen_Button   matlab.ui.control.Button
        VisioGuard2_Image      matlab.ui.control.Image
        MU2_Image              matlab.ui.control.Image
        Marco2_Image           matlab.ui.control.Image
        Tab3                   matlab.ui.container.Tab
        VisioGuard3_Image      matlab.ui.control.Image
        MU3_Image              matlab.ui.control.Image
        Marco3_Image           matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Continuar_Button
        function Continuar_ButtonPushed(app, event)
            if (isempty(app.Nombre_EditField.Value) || isempty(app.Apellidos_EditField.Value))
                app.ErrorInicio_Label.Visible = 'on'; %si alguna casilla vacia que salte el mensaje
            else
                app.ErrorInicio_Label.Visible = 'off';%si esta todo marcado que no aparezca
            end
            if(~isempty(app.Nombre_EditField.Value) && ~isempty(app.Apellidos_EditField.Value))
                app.TabGroup.SelectedTab = app.Tab2;  
            end
        end

        % Button pushed function: Inserteimagen_Button
        function Inserteimagen_ButtonPushed(app, event)
            [file, location] = uigetfile({'*.jpg';'*.png';}, 'Seleccione una imagen');

            if file ~= 0
                app.ErrorInsertar_Label.Visible='off';

                % Ubicacion de la imagen
                full_location = fullfile(location, file);
                % Mostrar la imagen en "Eye Fundus"
                app.EyeFundus_Image.ImageSource = full_location;
                %Mostrar la imagen y nombre paciente y esconder el boton para introducir la imagen
                app.EyeFundus_Image.Visible='on';
                app.Inserteimagen_Button.Visible='off';
                app.NuevoPaciente_Button.Visible='on';

                %Nombre y apellidos paciente
                app.Nombreyapellido_Label.Text=['Retinografía de ', app.Nombre_EditField.Value, ' ', app.Apellidos_EditField.Value];
                app.Nombreyapellido_Label.Visible='on';
            else
                app.ErrorInsertar_Label.Visible='on';
            end
        end

        % Button pushed function: NuevoPaciente_Button
        function NuevoPaciente_ButtonPushed(app, event)
            %Nuevo paciente (volver pagina principal)
            app.TabGroup.SelectedTab = app.VentanaInicio_Tab;
            %Imagen, labels y botones del tab2 de 0
            app.Nombre_EditField.Value='';
            app.Apellidos_EditField.Value='';
            app.EyeFundus_Image.Visible='off';
            app.Inserteimagen_Button.Visible='on';
            app.NuevoPaciente_Button.Visible='off';
            app.Nombreyapellido_Label.Visible='off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ventana1 = uifigure('Visible', 'off');
            app.ventana1.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.ventana1);
            app.VentanaInicio_Tab = uitab(app.TabGroup);
            app.VentanaInicio_Tab.Title = 'Ventana_inicio';
            app.Marco_Image = uiimage(app.VentanaInicio_Tab);
            app.Marco_Image.ImageSource = fullfile(pathToMLAPP, 'imagenes_app', 'marco.png');
            app.MU_Image = uiimage(app.VentanaInicio_Tab);
            app.MU_Image.ImageSource = fullfile(pathToMLAPP, 'imagenes_app', 'Logo_Mondragon_Unibertsitatea.png');
            app.VisioGuard_Image = uiimage(app.VentanaInicio_Tab);
            app.VisioGuard_Image.ImageSource = fullfile(pathToMLAPP, 'imagenes_app', 'Captura3-removebg-preview.png');
            app.Continuar_Button = uibutton(app.VentanaInicio_Tab, 'push');
            app.Continuar_Button.ButtonPushedFcn = createCallbackFcn(app, @Continuar_ButtonPushed, true);
            app.Continuar_Button.Text = 'Continuar';
            app.Apellidos_EditField = uieditfield(app.VentanaInicio_Tab, 'text');
            app.Nombre_EditField = uieditfield(app.VentanaInicio_Tab, 'text');
            app.ErrorInicio_Label = uilabel(app.VentanaInicio_Tab);
            app.ErrorInicio_Label.Visible = 'off';
            app.ErrorInicio_Label.Text = 'Asegurese de introducir su nombre y apellido';
            app.Apellidos_Label = uilabel(app.VentanaInicio_Tab);
            app.Apellidos_Label.Text = 'Apellidos';
            app.Nombre_Label = uilabel(app.VentanaInicio_Tab);
            app.Nombre_Label.Text = 'Nombre';
            app.Usuario_Image = uiimage(app.VentanaInicio_Tab);
            app.Usuario_Image.ImageSource = fullfile(pathToMLAPP, 'imagenes_app', 'user.png');
            app.Fecha_Label = uilabel(app.VentanaInicio_Tab);
            app.GlaucoScanLabel = uilabel(app.VentanaInicio_Tab);
            app.GlaucoScanLabel.Text = 'GlaucoScan';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.Marco2_Image = uiimage(app.Tab2);
            app.Marco2_Image.ImageSource = fullfile(pathToMLAPP, 'imagenes_app', 'marco.png');
            app.MU2_Image = uiimage(app.Tab2);
            app.MU2_Image.ImageSource = fullfile(pathToMLAPP, 'imagenes_app', 'Logo_Mondragon_Unibertsitatea.png');
            app.VisioGuard2_Image = uiimage(app.Tab2);
            app.VisioGuard2_Image.ImageSource = fullfile(pathToMLAPP, 'imagenes_app', 'Captura3-removebg-preview.png');
            app.Inserteimagen_Button = uibutton(app.Tab2, 'push');
            app.Inserteimagen_Button.ButtonPushedFcn = createCallbackFcn(app, @Inserteimagen_ButtonPushed, true);
            app.Inserteimagen_Button.Text = 'Inserte imágen';
            app.EyeFundus_Image = uiimage(app.Tab2);
            app.EyeFundus_Image.Visible = 'off';
            app.Nombreyapellido_Label = uilabel(app.Tab2);
            app.Nombreyapellido_Label.Visible = 'off';
            app.Nombreyapellido_Label.Text = 'Nombre y apellido';
            app.NuevoPaciente_Button = uibutton(app.Tab2, 'push');
            app.NuevoPaciente_Button.ButtonPushedFcn = createCallbackFcn(app, @NuevoPaciente_ButtonPushed, true);
            app.NuevoPaciente_Button.Visible = 'off';
            app.NuevoPaciente_Button.Text = 'Nuevo Paciente';
            app.ErrorInsertar_Label = uilabel(app.Tab2);
            app.ErrorInsertar_Label.Visible = 'off';
            app.ErrorInsertar_Label.Text = 'Ha ocurrido un error. Intente insertar otra imágen.';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.Marco3_Image = uiimage(app.Tab3);
            app.Marco3_Image.ImageSource = fullfile(pathToMLAPP, 'imagenes_app', 'marco.png');
            app.MU3_Image = uiimage(app.Tab3);
            app.MU3_Image.ImageSource = fullfile(pathToMLAPP, 'imagenes_app', 'Logo_Mondragon_Unibertsitatea.png');
            app.VisioGuard3_Image = uiimage(app.Tab3);
            app.VisioGuard3_Image.ImageSource = fullfile(pathToMLAPP, 'imagenes_app', 'Captura3-removebg-preview.png');
            % Show the figure after all components are created
            app.ventana1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VisioGuard_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ventana1)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ventana1)
        end
    end
end